classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        HidelinesCheckBox       matlab.ui.control.CheckBox
        SavefigureButton        matlab.ui.control.Button
        RandomiseButton         matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        DimensionsTab           matlab.ui.container.Tab
        HlSlider                matlab.ui.control.Slider
        HlSliderLabel           matlab.ui.control.Label
        HuSlider                matlab.ui.control.Slider
        HuSliderLabel           matlab.ui.control.Label
        WidthSlider             matlab.ui.control.Slider
        WidthSliderLabel        matlab.ui.control.Label
        LengthSlider            matlab.ui.control.Slider
        LengthSliderLabel       matlab.ui.control.Label
        EdgeControlTab          matlab.ui.container.Tab
        Nd1Slider               matlab.ui.control.Slider
        Nd1SliderLabel          matlab.ui.control.Label
        Nd0Slider               matlab.ui.control.Slider
        Nd0SliderLabel          matlab.ui.control.Label
        Nc1Slider               matlab.ui.control.Slider
        Nc1SliderLabel          matlab.ui.control.Label
        Nc0Slider               matlab.ui.control.Slider
        Nc0SliderLabel          matlab.ui.control.Label
        LateralControlTab       matlab.ui.container.Tab
        Acl1Slider              matlab.ui.control.Slider
        Acl1SliderLabel         matlab.ui.control.Label
        Acl0Slider              matlab.ui.control.Slider
        Acl0SliderLabel         matlab.ui.control.Label
        Acu1Slider              matlab.ui.control.Slider
        Acu1SliderLabel         matlab.ui.control.Label
        Acu0Slider              matlab.ui.control.Slider
        Acu0SliderLabel         matlab.ui.control.Label
        LongitudinalControlTab  matlab.ui.container.Tab
        Ad3Slider               matlab.ui.control.Slider
        Ad3SliderLabel          matlab.ui.control.Label
        Ad2Slider               matlab.ui.control.Slider
        Ad2SliderLabel          matlab.ui.control.Label
        Ad1Slider               matlab.ui.control.Slider
        Ad1SliderLabel          matlab.ui.control.Label
        Ad0Slider               matlab.ui.control.Slider
        Ad0SliderLabel          matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        length
        w
        h
        nc
        nd
        Au
        Al
        Ad
        xDisc = (0:0.01:1)'
        yDisc = 0:0.01:1
        x
        y
        z
    end
    
    methods (Access = public, Static)
        
        function S = shapefun(A, disc)
            
            n = length(A) - 1;
            S = 0;
            
            for i = 0:n
                
                K = nchoosek(n, i);
                
                Si = K * disc.^i .* (1 - disc).^(n-i);
                S = S + A(i+1) * Si;
            end
        end
        function C = classfun(n, disc, scale)
            
            if length(n) == 1, n(2) = n(1); end
            if nargin < 3 || isempty(scale), scale = 1; end
            
            C = scale * disc.^n(1) .* (1 - disc).^n(2);
        end
        function [yc, zc] = combine_upper_lower(zu, zl, yu, yl)
            
            if nargin < 5 || isempty(yl), yl = yu; end
            
            yc = [yu, fliplr(yl(:, 1:end-1))];
            zc = [zu, fliplr(zl(:, 1:end-1))];
        end
    end
    
    methods (Access = private)
        
        function main(app)
            
            app.length  = app.LengthSlider.Value;
            app.w       = app.WidthSlider.Value;
            app.h       = [app.HuSlider.Value, app.HlSlider.Value];
            app.nc      = [app.Nc0Slider.Value, app.Nc1Slider.Value];
            app.nd      = [app.Nd0Slider.Value, app.Nd1Slider.Value];
            app.Au      = [app.Acu0Slider.Value app.Acu1Slider.Value];
            app.Al      = [app.Acl0Slider.Value app.Acl1Slider.Value];
            app.Ad      = [app.Ad0Slider.Value, app.Ad1Slider.Value, ...
                           app.Ad2Slider.Value, app.Ad3Slider.Value];
            
            Scu = app.shapefun([app.Au, flip(app.Au)], app.yDisc);
            Scl = app.shapefun([app.Al, flip(app.Al)], app.yDisc);
            Sd  = app.shapefun(app.Ad, app.xDisc);
            Ccu = app.classfun(app.nc(1), app.yDisc);
            Ccl = app.classfun(app.nc(2), app.yDisc);
            Cd  = app.classfun(app.nd, app.xDisc);
            
            y_nd = -Sd .* Cd .* (1 - (2 * app.yDisc));
            zu_nd = app.h(1) * Ccu .* Scu .* (Cd .* Sd);
            zl_nd = app.h(2) * Ccl .* Scl .* (Cd .* Sd);
            
            % Max or max - min as min will always be 0
            y = y_nd./max(y_nd(:)) * app.w * app.length * 0.5;
            zu = zu_nd./max(zu_nd(:)) * app.h(1) * app.length * 0.5;
            zl = zl_nd./max(-zl_nd(:)) * app.h(2) * app.length * 0.5;
            
            [app.y, app.z] = app.combine_upper_lower(zu, zl, y);
            
            x = app.xDisc * app.length;
            app.x = repmat(x, 1, size(app.y, 2));
            app.plot;
        end
        
        function plot(app)
            
            v = get(app.UIAxes, 'View');
            colour = [0.5 0.5 0.5];
            mesh = surf(app.UIAxes, app.x, app.y, app.z, 'LineWidth', 0.05);
            set(mesh, 'FaceColor', colour, 'FaceLighting', 'flat');%'EdgeColor','none');
            
            lightangle(app.UIAxes, -20, 30)
            
            if app.HidelinesCheckBox.Value
                
                mesh.LineStyle = 'none';
            end
            
            view(app.UIAxes, v);
            
            Scu = app.shapefun([app.Au, flip(app.Au)], app.yDisc);
            Scl = app.shapefun([app.Al, flip(app.Al)], app.yDisc);
            Sd  = app.shapefun(app.Ad, app.xDisc);
            Ccu = app.classfun(app.nc(1), app.yDisc);
            Ccl = app.classfun(app.nc(2), app.yDisc);
            Cd  = app.classfun(app.nd, app.xDisc);
            
            cla(app.UIAxes2)
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, app.xDisc, Sd, 'r')
            plot(app.UIAxes2, app.xDisc, Cd, 'b')
            plot(app.UIAxes2, app.xDisc, Sd.*Cd, 'k')
            hold(app.UIAxes2, 'off')
            
            cla(app.UIAxes3)
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, app.yDisc, Scu, 'r')
            plot(app.UIAxes3, app.yDisc, Scl, 'r')
            plot(app.UIAxes3, app.yDisc, Ccu, 'b')
            plot(app.UIAxes3, app.yDisc, -Ccl, 'b')
            plot(app.UIAxes3, app.yDisc, Scu.*Ccu, 'k')
            plot(app.UIAxes3, app.yDisc, Scl.*Ccl, 'k')
            hLeg = legend(app.UIAxes3, ["Shape Function", "", "Class Function", "", "Combined", ""], 'location', 'northeastoutside');
            hLeg.Position(2) = hLeg.Position(2) + 0.12;
            hold(app.UIAxes3, 'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            view(app.UIAxes, [-45 25])
            title(app.UIAxes, '')
            axis(app.UIAxes, 'equal')
            box(app.UIAxes, 'on')
            grid(app.UIAxes, 'on')
            
            title(app.UIAxes2, 'Longitudinal')
            %xlim(app.UIAxes2, [0 1])
            %ylim(app.UIAxes2, [0 1])
            box(app.UIAxes2, 'on')
            % grid(app.UIAxes2, 'on')
            
            title(app.UIAxes3, 'Lateral')
            %xlim(app.UIAxes3, [0 1])
            %ylim(app.UIAxes3, [-1 1])
            box(app.UIAxes3, 'on')
            % grid(app.UIAxes3, 'on')
            app.main;
        end

        % Value changed function: LengthSlider
        function LengthSliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: WidthSlider
        function WidthSliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: HuSlider
        function HuSliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Nc0Slider
        function Nc0SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Nc1Slider
        function Nc1SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Nd0Slider
        function Nd0SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Nd1Slider
        function Nd1SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Acu0Slider
        function Acu0SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Acu1Slider
        function Acu1SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Acl0Slider
        function Acl0SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Acl1Slider
        function Acl1SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Ad0Slider
        function Ad0SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Ad1Slider
        function Ad1SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Ad2Slider
        function Ad2SliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: Ad3Slider
        function Ad3SliderValueChanged(app, event)
            app.main;
        end

        % Button pushed function: RandomiseButton
        function RandomiseButtonPushed(app, event)
            
            % For some reason this does not work
            % H = findobj(app, 'Type', 'Slider');
            % for i = 1:numel(H)
                
            %     H(i).Value = rand * diff(H(i).Limits) + H(i).Limits(1);
            % end
            
            props = properties(app);
            for i = 1:numel(props)
                
                H = app.(props{i});
                if isa(H, 'matlab.ui.control.Slider')
                    
                    H.Value = rand * diff(H.Limits) + H.Limits(1);
                end
            end
            
            app.main;
        end

        % Value changed function: HlSlider
        function HlSliderValueChanged(app, event)
            app.main;
        end

        % Value changed function: HidelinesCheckBox
        function HidelinesCheckBoxValueChanged(app, event)
            app.plot;
        end

        % Button pushed function: SavefigureButton
        function SavefigureButtonPushed(app, event)
            
            t = datetime('now', 'Format', 'yyyy-MM-dd''T''HH-mm-ss');
            
            % Working - Poor quality
            % name = ['bodydesign' char(t) '.png'];
            % exportapp(app.UIFigure, name);
            
            f = figure;
            axisHandles = findobj(app.UIFigure, 'type', 'axes');
            legHandles = findobj(app.UIFigure, 'type', 'legend');
            copyobj([axisHandles; legHandles], f);
            
            % Attempt 3
            % a = getframe(app.UIFigure);
            
            % Attempt
            %exportgraphics(app.UIFigure, name)
            
            % Attempt 2
            %export_fig(app.UIFigure, name, '-png', '-m1.5');
            
            % Attempt 1
            %tempFig = figure;
            %copyobj(app.UIAxes, tempFig)
            %hgsave(tempFig, [char(t) '.fig']);
            %close(tempFig)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {565, 565};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DimensionsTab = uitab(app.TabGroup);
            app.DimensionsTab.Title = 'Dimensions';
            app.LengthSliderLabel = uilabel(app.DimensionsTab);
            app.LengthSliderLabel.Text = 'Length';
            app.LengthSlider = uislider(app.DimensionsTab);
            app.LengthSlider.Limits = [1 50];
            app.LengthSlider.ValueChangedFcn = createCallbackFcn(app, @LengthSliderValueChanged, true);
            app.LengthSlider.Value = 10;
            app.WidthSliderLabel = uilabel(app.DimensionsTab);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.DimensionsTab);
            app.WidthSlider.Limits = [0.1 1];
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.WidthSlider.Value = 0.5;
            app.HuSliderLabel = uilabel(app.DimensionsTab);
            app.HuSliderLabel.Text = 'Hu';
            app.HuSlider = uislider(app.DimensionsTab);
            app.HuSlider.Limits = [0.1 1];
            app.HuSlider.ValueChangedFcn = createCallbackFcn(app, @HuSliderValueChanged, true);
            app.HuSlider.Value = 0.5;
            app.HlSliderLabel = uilabel(app.DimensionsTab);
            app.HlSliderLabel.Text = 'Hl';
            app.HlSlider = uislider(app.DimensionsTab);
            app.HlSlider.Limits = [0.1 1];
            app.HlSlider.ValueChangedFcn = createCallbackFcn(app, @HlSliderValueChanged, true);
            app.HlSlider.Value = 0.5;
            app.HlSlider = uislider(app.DimensionsTab);
            app.HlSlider.Limits = [0.1 1];
            app.HlSlider.ValueChangedFcn = createCallbackFcn(app, @HlSliderValueChanged, true);
            app.HlSlider.Value = 0.5;
            app.HlSliderLabel = uilabel(app.DimensionsTab);
            app.HlSliderLabel.Text = 'Hl';
            app.HuSlider = uislider(app.DimensionsTab);
            app.HuSlider.Limits = [0.1 1];
            app.HuSlider.ValueChangedFcn = createCallbackFcn(app, @HuSliderValueChanged, true);
            app.HuSlider.Value = 0.5;
            app.HuSliderLabel = uilabel(app.DimensionsTab);
            app.HuSliderLabel.Text = 'Hu';
            app.WidthSlider = uislider(app.DimensionsTab);
            app.WidthSlider.Limits = [0.1 1];
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.WidthSlider.Value = 0.5;
            app.WidthSliderLabel = uilabel(app.DimensionsTab);
            app.WidthSliderLabel.Text = 'Width';
            app.LengthSlider = uislider(app.DimensionsTab);
            app.LengthSlider.Limits = [1 50];
            app.LengthSlider.ValueChangedFcn = createCallbackFcn(app, @LengthSliderValueChanged, true);
            app.LengthSlider.Value = 10;
            app.LengthSliderLabel = uilabel(app.DimensionsTab);
            app.LengthSliderLabel.Text = 'Length';
            app.EdgeControlTab = uitab(app.TabGroup);
            app.EdgeControlTab.Title = 'Edge Control';
            app.Nc0SliderLabel = uilabel(app.EdgeControlTab);
            app.Nc0SliderLabel.Text = 'Nc0';
            app.Nc0Slider = uislider(app.EdgeControlTab);
            app.Nc0Slider.Limits = [0.05 0.95];
            app.Nc0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nc0Slider.ValueChangedFcn = createCallbackFcn(app, @Nc0SliderValueChanged, true);
            app.Nc0Slider.Value = 0.5;
            app.Nc1SliderLabel = uilabel(app.EdgeControlTab);
            app.Nc1SliderLabel.Text = 'Nc1';
            app.Nc1Slider = uislider(app.EdgeControlTab);
            app.Nc1Slider.Limits = [0.05 0.95];
            app.Nc1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nc1Slider.ValueChangedFcn = createCallbackFcn(app, @Nc1SliderValueChanged, true);
            app.Nc1Slider.Value = 0.5;
            app.Nd0SliderLabel = uilabel(app.EdgeControlTab);
            app.Nd0SliderLabel.Text = 'Nd0';
            app.Nd0Slider = uislider(app.EdgeControlTab);
            app.Nd0Slider.Limits = [0.05 0.95];
            app.Nd0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nd0Slider.ValueChangedFcn = createCallbackFcn(app, @Nd0SliderValueChanged, true);
            app.Nd0Slider.Value = 0.5;
            app.Nd1SliderLabel = uilabel(app.EdgeControlTab);
            app.Nd1SliderLabel.Text = 'Nd1';
            app.Nd1Slider = uislider(app.EdgeControlTab);
            app.Nd1Slider.Limits = [0.05 0.95];
            app.Nd1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nd1Slider.ValueChangedFcn = createCallbackFcn(app, @Nd1SliderValueChanged, true);
            app.Nd1Slider.Value = 0.5;
            app.Nd1Slider = uislider(app.EdgeControlTab);
            app.Nd1Slider.Limits = [0.05 0.95];
            app.Nd1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nd1Slider.ValueChangedFcn = createCallbackFcn(app, @Nd1SliderValueChanged, true);
            app.Nd1Slider.Value = 0.5;
            app.Nd1SliderLabel = uilabel(app.EdgeControlTab);
            app.Nd1SliderLabel.Text = 'Nd1';
            app.Nd0Slider = uislider(app.EdgeControlTab);
            app.Nd0Slider.Limits = [0.05 0.95];
            app.Nd0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nd0Slider.ValueChangedFcn = createCallbackFcn(app, @Nd0SliderValueChanged, true);
            app.Nd0Slider.Value = 0.5;
            app.Nd0SliderLabel = uilabel(app.EdgeControlTab);
            app.Nd0SliderLabel.Text = 'Nd0';
            app.Nc1Slider = uislider(app.EdgeControlTab);
            app.Nc1Slider.Limits = [0.05 0.95];
            app.Nc1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nc1Slider.ValueChangedFcn = createCallbackFcn(app, @Nc1SliderValueChanged, true);
            app.Nc1Slider.Value = 0.5;
            app.Nc1SliderLabel = uilabel(app.EdgeControlTab);
            app.Nc1SliderLabel.Text = 'Nc1';
            app.Nc0Slider = uislider(app.EdgeControlTab);
            app.Nc0Slider.Limits = [0.05 0.95];
            app.Nc0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Nc0Slider.ValueChangedFcn = createCallbackFcn(app, @Nc0SliderValueChanged, true);
            app.Nc0Slider.Value = 0.5;
            app.Nc0SliderLabel = uilabel(app.EdgeControlTab);
            app.Nc0SliderLabel.Text = 'Nc0';
            app.LateralControlTab = uitab(app.TabGroup);
            app.LateralControlTab.Title = 'Lateral Control';
            app.Acu0SliderLabel = uilabel(app.LateralControlTab);
            app.Acu0SliderLabel.Text = 'Acu0';
            app.Acu0Slider = uislider(app.LateralControlTab);
            app.Acu0Slider.Limits = [0.05 0.95];
            app.Acu0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Acu0Slider.ValueChangedFcn = createCallbackFcn(app, @Acu0SliderValueChanged, true);
            app.Acu0Slider.Value = 0.5;
            app.Acu1SliderLabel = uilabel(app.LateralControlTab);
            app.Acu1SliderLabel.Text = 'Acu1';
            app.Acu1Slider = uislider(app.LateralControlTab);
            app.Acu1Slider.Limits = [0.05 0.95];
            app.Acu1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Acu1Slider.ValueChangedFcn = createCallbackFcn(app, @Acu1SliderValueChanged, true);
            app.Acu1Slider.Value = 0.5;
            app.Acl0SliderLabel = uilabel(app.LateralControlTab);
            app.Acl0SliderLabel.Text = 'Acl0';
            app.Acl0Slider = uislider(app.LateralControlTab);
            app.Acl0Slider.Limits = [-0.95 -0.05];
            app.Acl0Slider.MajorTicks = [-0.95 -0.75 -0.55 -0.35 -0.15];
            app.Acl0Slider.ValueChangedFcn = createCallbackFcn(app, @Acl0SliderValueChanged, true);
            app.Acl0Slider.Value = -0.5;
            app.Acl1SliderLabel = uilabel(app.LateralControlTab);
            app.Acl1SliderLabel.Text = 'Acl1';
            app.Acl1Slider = uislider(app.LateralControlTab);
            app.Acl1Slider.Limits = [-0.95 -0.05];
            app.Acl1Slider.MajorTicks = [-0.95 -0.75 -0.55 -0.35 -0.15];
            app.Acl1Slider.ValueChangedFcn = createCallbackFcn(app, @Acl1SliderValueChanged, true);
            app.Acl1Slider.Value = -0.5;
            app.Acl1Slider = uislider(app.LateralControlTab);
            app.Acl1Slider.Limits = [-0.95 -0.05];
            app.Acl1Slider.MajorTicks = [-0.95 -0.75 -0.55 -0.35 -0.15];
            app.Acl1Slider.ValueChangedFcn = createCallbackFcn(app, @Acl1SliderValueChanged, true);
            app.Acl1Slider.Value = -0.5;
            app.Acl1SliderLabel = uilabel(app.LateralControlTab);
            app.Acl1SliderLabel.Text = 'Acl1';
            app.Acl0Slider = uislider(app.LateralControlTab);
            app.Acl0Slider.Limits = [-0.95 -0.05];
            app.Acl0Slider.MajorTicks = [-0.95 -0.75 -0.55 -0.35 -0.15];
            app.Acl0Slider.ValueChangedFcn = createCallbackFcn(app, @Acl0SliderValueChanged, true);
            app.Acl0Slider.Value = -0.5;
            app.Acl0SliderLabel = uilabel(app.LateralControlTab);
            app.Acl0SliderLabel.Text = 'Acl0';
            app.Acu1Slider = uislider(app.LateralControlTab);
            app.Acu1Slider.Limits = [0.05 0.95];
            app.Acu1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Acu1Slider.ValueChangedFcn = createCallbackFcn(app, @Acu1SliderValueChanged, true);
            app.Acu1Slider.Value = 0.5;
            app.Acu1SliderLabel = uilabel(app.LateralControlTab);
            app.Acu1SliderLabel.Text = 'Acu1';
            app.Acu0Slider = uislider(app.LateralControlTab);
            app.Acu0Slider.Limits = [0.05 0.95];
            app.Acu0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Acu0Slider.ValueChangedFcn = createCallbackFcn(app, @Acu0SliderValueChanged, true);
            app.Acu0Slider.Value = 0.5;
            app.Acu0SliderLabel = uilabel(app.LateralControlTab);
            app.Acu0SliderLabel.Text = 'Acu0';
            app.LongitudinalControlTab = uitab(app.TabGroup);
            app.LongitudinalControlTab.Title = 'Longitudinal Control';
            app.Ad0SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad0SliderLabel.Text = 'Ad0';
            app.Ad0Slider = uislider(app.LongitudinalControlTab);
            app.Ad0Slider.Limits = [0.05 0.95];
            app.Ad0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad0Slider.ValueChangedFcn = createCallbackFcn(app, @Ad0SliderValueChanged, true);
            app.Ad0Slider.Value = 0.5;
            app.Ad1SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad1SliderLabel.Text = 'Ad1';
            app.Ad1Slider = uislider(app.LongitudinalControlTab);
            app.Ad1Slider.Limits = [0.05 0.95];
            app.Ad1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad1Slider.ValueChangedFcn = createCallbackFcn(app, @Ad1SliderValueChanged, true);
            app.Ad1Slider.Value = 0.5;
            app.Ad2SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad2SliderLabel.Text = 'Ad2';
            app.Ad2Slider = uislider(app.LongitudinalControlTab);
            app.Ad2Slider.Limits = [0.05 0.95];
            app.Ad2Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad2Slider.ValueChangedFcn = createCallbackFcn(app, @Ad2SliderValueChanged, true);
            app.Ad2Slider.Value = 0.5;
            app.Ad3SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad3SliderLabel.Text = 'Ad3';
            app.Ad3Slider = uislider(app.LongitudinalControlTab);
            app.Ad3Slider.Limits = [0.05 0.95];
            app.Ad3Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad3Slider.ValueChangedFcn = createCallbackFcn(app, @Ad3SliderValueChanged, true);
            app.Ad3Slider.Value = 0.5;
            app.Ad3Slider = uislider(app.LongitudinalControlTab);
            app.Ad3Slider.Limits = [0.05 0.95];
            app.Ad3Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad3Slider.ValueChangedFcn = createCallbackFcn(app, @Ad3SliderValueChanged, true);
            app.Ad3Slider.Value = 0.5;
            app.Ad3SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad3SliderLabel.Text = 'Ad3';
            app.Ad2Slider = uislider(app.LongitudinalControlTab);
            app.Ad2Slider.Limits = [0.05 0.95];
            app.Ad2Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad2Slider.ValueChangedFcn = createCallbackFcn(app, @Ad2SliderValueChanged, true);
            app.Ad2Slider.Value = 0.5;
            app.Ad2SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad2SliderLabel.Text = 'Ad2';
            app.Ad1Slider = uislider(app.LongitudinalControlTab);
            app.Ad1Slider.Limits = [0.05 0.95];
            app.Ad1Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad1Slider.ValueChangedFcn = createCallbackFcn(app, @Ad1SliderValueChanged, true);
            app.Ad1Slider.Value = 0.5;
            app.Ad1SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad1SliderLabel.Text = 'Ad1';
            app.Ad0Slider = uislider(app.LongitudinalControlTab);
            app.Ad0Slider.Limits = [0.05 0.95];
            app.Ad0Slider.MajorTicks = [0.05 0.25 0.45 0.65 0.85];
            app.Ad0Slider.ValueChangedFcn = createCallbackFcn(app, @Ad0SliderValueChanged, true);
            app.Ad0Slider.Value = 0.5;
            app.Ad0SliderLabel = uilabel(app.LongitudinalControlTab);
            app.Ad0SliderLabel.Text = 'Ad0';
            app.RandomiseButton = uibutton(app.LeftPanel, 'push');
            app.RandomiseButton.ButtonPushedFcn = createCallbackFcn(app, @RandomiseButtonPushed, true);
            app.RandomiseButton.Text = 'Randomise';
            app.SavefigureButton = uibutton(app.LeftPanel, 'push');
            app.SavefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButtonPushed, true);
            app.SavefigureButton.Text = 'Save figure';
            app.HidelinesCheckBox = uicheckbox(app.LeftPanel);
            app.HidelinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidelinesCheckBoxValueChanged, true);
            app.HidelinesCheckBox.Text = 'Hide lines';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'x, m')
            ylabel(app.UIAxes, 'y, m')
            zlabel(app.UIAxes, 'z, m')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Longitudinal')
            xlabel(app.UIAxes2, 'x')
            ylabel(app.UIAxes2, 'y')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Lateral')
            xlabel(app.UIAxes3, 'y')
            ylabel(app.UIAxes3, 'z')
            app.UIAxes3.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes3.YTick = [-1 -0.6 -0.2 0.2 0.6 1];
            app.UIAxes3.YTickLabel = {'-1'; '-0.6'; '-0.2'; '0.2'; '0.6'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end